classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        importButton_2                  matlab.ui.control.Button
        MGFLabel_2                      matlab.ui.control.Label
        MGFLabel                        matlab.ui.control.Label
        RVLabel                         matlab.ui.control.Label
        MEANLabel                       matlab.ui.control.Label
        MGF0EditField_2                 matlab.ui.control.EditField
        MGF0EditField                   matlab.ui.control.EditField
        ListBox                         matlab.ui.control.ListBox
        variance                        matlab.ui.control.EditField
        mean                            matlab.ui.control.EditField
        VARLabel                        matlab.ui.control.Label
        ThirdMGFLabel                   matlab.ui.control.Label
        third_MGF                       matlab.ui.control.EditField
        plotButton_2                    matlab.ui.control.Button
        RVEditField                     matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        Tab2                            matlab.ui.container.Tab
        nth_timemeanEditField           matlab.ui.control.EditField
        nth_timemeanEditFieldLabel      matlab.ui.control.Label
        j_thEditField                   matlab.ui.control.EditField
        j_thEditFieldLabel              matlab.ui.control.Label
        i_thEditField                   matlab.ui.control.EditField
        i_thEditFieldLabel              matlab.ui.control.Label
        Pn_thmeanACFEditField           matlab.ui.control.EditField
        Pn_thmeanACFEditFieldLabel      matlab.ui.control.Label
        importButton                    matlab.ui.control.Button
        plotButton                      matlab.ui.control.Button
        totalavragepowerEditField       matlab.ui.control.EditField
        totalavragepowerEditFieldLabel  matlab.ui.control.Label
        ListBox_2                       matlab.ui.control.ListBox
        ListBox_2Label                  matlab.ui.control.Label
        EnterMsamplesEditField          matlab.ui.control.EditField
        EnterMsamplesEditFieldLabel     matlab.ui.control.Label
        RandomProcessEditField          matlab.ui.control.EditField
        RandomProcessEditFieldLabel     matlab.ui.control.Label
        RandomProcressLabel             matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
    end

    
    methods (Access = private)
       
        function mgf = moment_generating_function(app,t, data)
             % Check if the input data is empty
            if isempty(data)
                error('Input data is empty.');
            end
        
            % Initialize sum
            sum_exp_tX = 0;
        
            % Calculate the sum of exp(t * X) for each element in data
            for o = 1:numel(data)
                sum_exp_tX = sum_exp_tX + exp(t * data(o));
            end
        
            % Calculate the mean of exp(t * X)
            mgf = sum_exp_tX / numel(data);    
        end
        
        function MGF1 = moment_generating_function1(app,t, data)
             % Check if the input data is empty
            if isempty(data)
                error('Input data is empty.');
            end
        
            % Initialize sum
            sum_exp_tX = 0;
        
            % Calculate the sum of exp(t * X) for each element in data
            for o = 1:numel(data)
                sum_exp_tX = sum_exp_tX + data(o)*exp(t * data(o));
            end
        
            % Calculate the mean of exp(t * X)
            MGF1 = sum_exp_tX / numel(data);    

            
        end
        function MGF2 = moment_generating_function2(app,t, data)
             % Check if the input data is empty
            if isempty(data)
                error('Input data is empty.');
            end
        
            % Initialize sum
            sum_exp_tX = 0;
        
            % Calculate the sum of exp(t * X) for each element in data
            for o = 1:numel(data)
                sum_exp_tX = sum_exp_tX  +data(o)*data(o)*exp(t * data(o));
            end
        
            % Calculate the mean of exp(t * X)
            MGF2 = sum_exp_tX / numel(data);    

            
        end
        function MGF3 = moment_generating_function3(app,t, data)
             % Check if the input data is empty
            if isempty(data)
                error('Input data is empty.');
            end
        
            % Initialize sum
            sum_exp_tX = 0;
        
            % Calculate the sum of exp(t * X) for each element in data
            for o = 1:numel(data)
                sum_exp_tX = sum_exp_tX +data(o)*data(o)*data(o)*exp(t * data(o));
            end
        
            % Calculate the mean of exp(t * X)
            MGF3 = sum_exp_tX / numel(data);    

            
        end
        
function acf_values = autocorrelation(app,time_series, max_lag)
    % time_series: the input time series
    % max_lag: maximum lag for autocorrelation
    
    % Calculate the mean of the time series
    mean_series = mean(time_series);
    
    % Initialize array to store autocorrelation values
    acf_values = zeros(1, max_lag + 1);
    
    % Calculate autocorrelation for each lag
    for k = 0:max_lag
        numerator = 0;
        denominator = 0;
        
        % Summation for autocorrelation calculation
        for t = (k + 1):length(time_series)
            numerator = numerator + (time_series(t) - mean_series) * (time_series(t - k) - mean_series);
            denominator = denominator + (time_series(t) - mean_series)^2;
        end
        
        % Autocorrelation value at lag k
        acf_values(k + 1) = numerator / denominator;
    end
     % Plot the autocorrelation function
    
    plot(app.UIAxes4,0:max_lag, acf_values);

end

        
function ACF = acffunc(app,ensemble,t)
            num_realizations = size(ensemble, 1);
    num_points = size(ensemble, 2);
    i_th =str2double(app.i_thEditField.Value);
    j_th =str2double(app.j_thEditField.Value);
    
    % Initialize matrix to store auto-correlation results
    acf_matrix = zeros(num_points, num_points, num_realizations);
    
    % Calculate auto-correlation for each realization
    for r = 1:num_realizations
        MEAN = sum(ensemble(r, :))/num_points;
        denominator = sum((ensemble(r, :) - MEAN) .^ 2);
        
        for i = i_th:j_th
            for j =  i_th:j_th
                k = abs(i-j); % Calculate lag
                numerator = 0;
                % Compute the numerator for the given lag k
                for t = (k+1):num_points
                    numerator = numerator + (ensemble(r, t) - MEAN) * (ensemble(r, t-k) - MEAN);
                end
                
                % Store the normalized autocorrelation value for this i,j pair
                acf_matrix(i, j, r) = numerator / denominator;
            end
        end
    end

    % Average across all realizations
    average_acf = mean(acf_matrix, 3);
    
    % 3D Plotting
    [i_grid, j_grid] = meshgrid(1:num_points, 1:num_points);
    surf(app.UIAxes4,i_grid, j_grid, average_acf);
    title(app.UIAxes4, '3d ACF')
    xlabel(app.UIAxes4, 'i')
    ylabel(app.UIAxes4, 'j')
    zlabel(app.UIAxes4, 'ACF')


    ACF=1;
    end
        
    function psd=  PSDfuc(app,ensemble,t)
            [num_samples, num_points] = size(ensemble);

            % Calculate FFT for each sample
            fft_X = fft(ensemble, [], 2);
            
            % Calculate power spectrum (magnitude squared of the FFT)
            power_spectrum = abs(fft_X).^2;
            
            % Normalize to get the power spectral density (PSD)
            psd = power_spectrum / num_points;
            
            % Average the PSD across all samples
            avg_psd = mean(psd, 1);
            
            
            % Frequency vector (assuming uniform sampling)
            dt = t(2) - t(1); % Time difference between samples
            frequencies = linspace(0, 1/dt, num_points);
            
            % Plot the averaged PSD
            plot(app.UIAxes4,frequencies, avg_psd);
            title(app.UIAxes4, 'averaged PSD')
            xlabel(app.UIAxes4, 'frequencies')
            ylabel(app.UIAxes4, 'avg_psd')
            % Calculate the total average power
            app.totalavragepowerEditField.Value = num2str(sum(avg_psd));
            psd=1;

            
        end
        
        function t_acf = TIMEACF(app,ensemble,time1)
            n=str2double(app.Pn_thmeanACFEditField.Value);
            signal = ensemble(n,:); 

            % Length of the signal
            T = length(signal);
            
            % Initialize the array to store autocorrelation values
            % Here we assume the maximum lag is T-1, which is the length of the signal
            time_auto_correlation = zeros(T, 1);
            
            % Calculate time autocorrelation for each lag
            for tau = 0:T-1
                sum_timeacf = 0;
                for t = 1:T-tau
                    sum_timeacf = sum_timeacf + signal(t) * signal(t + tau);
                end
                % Normalizing by the length of the signal for the given lag
                time_auto_correlation(tau + 1) = sum_timeacf / (max(time1)-min(time1));
               

            end
             time_auto_correlation=time_auto_correlation/max(time_auto_correlation);
            
            % Plot the autocorrelation function
            lags = 0:T-1;
            plot(app.UIAxes4,lags, time_auto_correlation);
            title(app.UIAxes4, 'Time ACF')
            xlabel(app.UIAxes4, 'lags')
            ylabel(app.UIAxes4, 'time_auto_correlation')
            t_acf=1;


        end
        
        function dACF = DACF_T_ACF(app,ensemble,t)
             n=str2double(app.Pn_thmeanACFEditField.Value);        

            num_realizations = size(ensemble, 1);
            num_points = size(ensemble, 2);
            
            realization_index = n;
            acf_values = zeros(num_points, 1);
            MEAN = sum(ensemble(realization_index, :))/num_points;
            denominator = sum((ensemble(realization_index, :) - MEAN) .^ 2);
            
            % Loop over all possible lags
            for lag = 0:num_points-1
                numerator = 0;
                for t = (lag+1):num_points
                    numerator = numerator + (ensemble(realization_index, t) - MEAN) * (ensemble(realization_index, t-lag) - MEAN);
                end
                acf_values(lag+1) = numerator / denominator;
            end
            
            % Plot the 2D autocorrelation function for the selected realization
            lags = 0:num_points-1;
            plot(app.UIAxes4,lags, acf_values);
            title(app.UIAxes4,'2D Auto-Correlation Function for Realization ');
            xlabel(app.UIAxes4,'Lag');
            ylabel(app.UIAxes4,'Auto-Correlation Value');
            dACF=1;


        end
        

end
            

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: plotButton_2
        function plotButton_2Pushed(app, event)
            
            loadedData = load(app.RVEditField.Value);
            % Access the DATA
            x = loadedData.s;  % Replace 'Y' with the correct field name
        
            % Define t_values
            t_values = linspace(0, 2, 1000);
        
            if app.ListBox.ValueIndex ==1
                % Calculate the MGF values
                mgf_values = zeros(size(t_values));
                for i = 1:length(t_values)
                    mgf_values(i) = moment_generating_function(app,t_values(i), x);
                end

                % Plot the MGF
                plot(app.UIAxes, t_values, mgf_values);
                title(app.UIAxes, ' MGF')
                xlabel(app.UIAxes, 'time')
                ylabel(app.UIAxes, 'mgf_values')
            elseif app.ListBox.ValueIndex ==2
                 % Calculate the first MGF values
                mgf_values1 = zeros(size(t_values));
                for i = 1:length(t_values)
                    mgf_values1(i) = moment_generating_function1(app,t_values(i), x);
                end

                % Plot the MGF
                plot(app.UIAxes, t_values, mgf_values1);
                title(app.UIAxes, 'first MGF')
                xlabel(app.UIAxes, 'time')
                ylabel(app.UIAxes, 'mgf_values1')
                
            else
                 % Calculate the second MGF values
                mgf_values2 = zeros(size(t_values));
                for i = 1:length(t_values)
                    mgf_values2(i) = moment_generating_function2(app,t_values(i), x);
                end  
                         plot(app.UIAxes, t_values, mgf_values2);
                        title(app.UIAxes, 'second MGF')
                        xlabel(app.UIAxes, 'time')
                        ylabel(app.UIAxes, 'mgf_values2')
                                                     % Plot the MGF


            end

            % app.UIAxes.Title=num2str(app.ListBox.Value);
            % app.UIAxes.XLabel='time';
            % app.UIAxes.XLabel= app.ListBox.Value;
            app.mean.Value=num2str(moment_generating_function1(app,0,x));
            app.variance.Value =num2str(moment_generating_function2(app,0,x) - (moment_generating_function1(app,0,x))*(moment_generating_function1(app,0,x)));
            app.third_MGF.Value =num2str( moment_generating_function3(app,0,x));
            app.MGF0EditField.Value=num2str(moment_generating_function1(app,0,x)) ;
            app.MGF0EditField_2.Value=num2str(moment_generating_function2(app,0,x)) ;


        end

        % Button pushed function: importButton_2
        function importButton_2Pushed(app, event)
            [filename,path]=uigetfile();
            app.RVEditField.Value=filename;
            figure(app.UIFigure);
        end

        % Button pushed function: importButton
        function importButtonPushed(app, event)
            [filename,path]=uigetfile();
            app.RandomProcessEditField.Value=filename;
            figure(app.UIFigure);
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
              loadedData = load(app.RandomProcessEditField.Value);
            % Access the DATA
            time = loadedData.t;  % Replace 'Y' with the correct field name
            sample = loadedData.X;

            if app.ListBox_2.ValueIndex ==1
                % Calculate the mean values
                           Mean = zeros(size(time));
                           for i = 1:length(time)
                                Mean(i) = sum(sample(:,i))/size(sample,1);
                           end 
                           plot(app.UIAxes4, time, Mean);
                            title(app.UIAxes4, 'ensamble mean')
                            xlabel(app.UIAxes4, 'time')
                            ylabel(app.UIAxes4, 'mean')
            elseif  app.ListBox_2.ValueIndex ==2 
                % plot  the m  samples
                m=str2double(app.EnterMsamplesEditField.Value);
                randomNumber = randi([1, size(sample,1)], 1, m);
                for i = 1:m
                     subplot(m,1,i);
                     plot(time, sample(randomNumber(:,i),:));
                     xlabel('time');
                     ylabel('X(t)');
                      title('m samples');
                     grid on
                end
                plot(app.UIAxes4, time, sample(randomNumber,:));
                title(app.UIAxes4, 'm samples ')
                xlabel(app.UIAxes4, 'time')
                ylabel(app.UIAxes4, 'X(t)')

             elseif  app.ListBox_2.ValueIndex ==3
                 %plot auto corelation 
                        g= acffunc(app,sample);

             elseif  app.ListBox_2.ValueIndex ==4
                 %calculate nth mean
                  n=str2double(app.Pn_thmeanACFEditField.Value);
                  app.nth_timemeanEditField.Value=num2str(moment_generating_function1(app,0,sample(n,:)));
              elseif  app.ListBox_2.ValueIndex ==5
                   %calculate psd

                   l = PSDfuc(app,sample,time);
           elseif  app.ListBox_2.ValueIndex ==6
           ta = TIMEACF(app,sample,time);


            else
              DACF_T_ACF(app,sample,time);


            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'lines');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RVEditField = uieditfield(app.Tab, 'text');
            app.plotButton_2 = uibutton(app.Tab, 'push');
            app.plotButton_2.ButtonPushedFcn = createCallbackFcn(app, @plotButton_2Pushed, true);
            app.plotButton_2.Text = 'plot';
            app.third_MGF = uieditfield(app.Tab, 'text');
            app.ThirdMGFLabel = uilabel(app.Tab);
            app.ThirdMGFLabel.Text = 'Third MGF';
            app.VARLabel = uilabel(app.Tab);
            app.VARLabel.Text = 'VAR';
            app.mean = uieditfield(app.Tab, 'text');
            app.variance = uieditfield(app.Tab, 'text');
            app.ListBox = uilistbox(app.Tab);
            app.ListBox.Items = {'MGF', 'MGF''', 'MGF''''', ''};
            app.ListBox.Value = 'MGF';
            app.MGF0EditField = uieditfield(app.Tab, 'text');
            app.MGF0EditField_2 = uieditfield(app.Tab, 'text');
            app.MEANLabel = uilabel(app.Tab);
            app.MEANLabel.Text = 'MEAN ';
            app.RVLabel = uilabel(app.Tab);
            app.RVLabel.Text = 'RV';
            app.MGFLabel = uilabel(app.Tab);
            app.MGFLabel.Text = 'MGF''';
            app.MGFLabel_2 = uilabel(app.Tab);
            app.MGFLabel_2.Text = 'MGF"';
            app.importButton_2 = uibutton(app.Tab, 'push');
            app.importButton_2.ButtonPushedFcn = createCallbackFcn(app, @importButton_2Pushed, true);
            app.importButton_2.Text = 'import';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes4 = uiaxes(app.Tab2);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.RandomProcressLabel = uilabel(app.Tab2);
            app.RandomProcressLabel.Text = 'Random Procress';
            app.RandomProcessEditFieldLabel = uilabel(app.Tab2);
            app.RandomProcessEditFieldLabel.Text = 'Random Process';
            app.RandomProcessEditField = uieditfield(app.Tab2, 'text');
            app.EnterMsamplesEditFieldLabel = uilabel(app.Tab2);
            app.EnterMsamplesEditFieldLabel.Text = 'Enter M samples ';
            app.EnterMsamplesEditField = uieditfield(app.Tab2, 'text');
            app.ListBox_2Label = uilabel(app.Tab2);
            app.ListBox_2Label.Text = 'List Box';
            app.ListBox_2 = uilistbox(app.Tab2);
            app.ListBox_2.Items = {'ensamble mean', 'plot m sample', 'ACF', 'n_mean', 'PSD', 'Time_ACF', 'n_th ACF 2D', '', ''};
            app.ListBox_2.Value = 'ensamble mean';
            app.totalavragepowerEditFieldLabel = uilabel(app.Tab2);
            app.totalavragepowerEditFieldLabel.Text = 'total avrage power';
            app.totalavragepowerEditField = uieditfield(app.Tab2, 'text');
            app.plotButton = uibutton(app.Tab2, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.importButton = uibutton(app.Tab2, 'push');
            app.importButton.ButtonPushedFcn = createCallbackFcn(app, @importButtonPushed, true);
            app.importButton.Text = 'import';
            app.Pn_thmeanACFEditFieldLabel = uilabel(app.Tab2);
            app.Pn_thmeanACFEditFieldLabel.Text = 'P-> n_th mean/ACF ';
            app.Pn_thmeanACFEditField = uieditfield(app.Tab2, 'text');
            app.i_thEditFieldLabel = uilabel(app.Tab2);
            app.i_thEditFieldLabel.Text = 'i_th';
            app.i_thEditField = uieditfield(app.Tab2, 'text');
            app.j_thEditFieldLabel = uilabel(app.Tab2);
            app.j_thEditFieldLabel.Text = 'j_th';
            app.j_thEditField = uieditfield(app.Tab2, 'text');
            app.nth_timemeanEditFieldLabel = uilabel(app.Tab2);
            app.nth_timemeanEditFieldLabel.Text = 'nth_time mean';
            app.nth_timemeanEditField = uieditfield(app.Tab2, 'text');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end